classdef exp_02_vibroSensitivityTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        LeftPanel                    matlab.ui.container.Panel
        GridLayout2                  matlab.ui.container.GridLayout
        Bluetooth                    matlab.ui.container.Panel
        AvailableDevicesListBox      matlab.ui.control.ListBox
        ConnectButton                matlab.ui.control.Button
        DisconnectButton             matlab.ui.control.Button
        Lamp                         matlab.ui.control.Lamp
        Acceleration                 matlab.ui.container.Panel
        subjectNumberEditField       matlab.ui.control.EditField
        subjectNumberEditFieldLabel  matlab.ui.control.Label
        BTN_Connect                  matlab.ui.control.StateButton
        BTN_Record                   matlab.ui.control.StateButton
        DropDown_COM                 matlab.ui.control.DropDown
        TabGroup                     matlab.ui.container.TabGroup
        ManualTab                    matlab.ui.container.Tab
        LRA_rightButton              matlab.ui.control.Button
        LRA_leftButton               matlab.ui.control.Button
        AmplitudeEditField           matlab.ui.control.NumericEditField
        AmplitudeEditFieldLabel      matlab.ui.control.Label
        DurationmsEditField          matlab.ui.control.NumericEditField
        DurationmsEditFieldLabel     matlab.ui.control.Label
        AutoTab                      matlab.ui.container.Tab
        GoLRARightButton             matlab.ui.control.Button
        AmplitudeButtonGroup         matlab.ui.container.ButtonGroup
        Button20                     matlab.ui.control.RadioButton
        Button40                     matlab.ui.control.RadioButton
        Button60                     matlab.ui.control.RadioButton
        Button80                     matlab.ui.control.RadioButton
        Button100                    matlab.ui.control.RadioButton
        LocationButtonGroup          matlab.ui.container.ButtonGroup
        LateralButton                matlab.ui.control.RadioButton
        PosteriorCalibrationButton   matlab.ui.control.RadioButton
        MedialButton                 matlab.ui.control.RadioButton
        AnteriorButton               matlab.ui.control.RadioButton
        TestingTab                   matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        IterationnumberLabel         matlab.ui.control.Label
        CuePairedButton              matlab.ui.control.Button
        LoadPairingsButton           matlab.ui.control.Button
        RightPanel                   matlab.ui.container.Panel
        GridLayout3                  matlab.ui.container.GridLayout
        UIAxesLRA_right              matlab.ui.control.UIAxes
        UIAxesLRA_left               matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        isStreaming;
        timerStreamHandle;
        timerPlotHandle;
        timerDevicesHandle;
        serialHandle; % Description
        
        GaitGuide;
        availableDevices;
        
        acc_timestamp;
        stim_timestamps;
        
        USBstream uint8;
        dataRight;
        dataLeft;
        subjectNumber;
        selectedLocation;
    end

    properties (Access = public)
        pairings_iterations = 1;
        pairings;
        responses; 
    end
    
    methods (Access = private)
        
        function timerStreamCallback(app, obj, event)
            app.readStream();
        end
        
        function timerDevicesCallback(app, obj, event)
            if(numel(app.GaitGuide) && app.GaitGuide.Connected)
                app.Lamp.Color = "blue";
                %  set(app.TabGroup, 'visible', 'on');
            else
                app.Lamp.Color = "#a6a6a6";
                set(app.TabGroup, 'visible', 'off');
                app.AvailableDevicesListBox.Items = blelist('name', 'GaitGuide').Address;
            end
            
            
        end


        function timerPlotCallback(app, obj, event)
            
            acc = 16;
            scale = 32768 / acc;
            
            Fs = 26667;
            Ts = 1/Fs;
%             if(numel(app.dataRight))
%                 tsRight = 0:Ts:((numel(app.dataRight)-1)/Fs);
%                 
%                 plot(app.UIAxesLRA_right,tsRight,app.dataRight./scale);
%                 yline(app.UIAxesLRA_right,0);
%                 yline(app.UIAxesLRA_right,acc,'--r');
%                 yline(app.UIAxesLRA_right,-(acc),'--r');
%             end
%             % NR COMMENTED OUT FOR 11.21.2023
%             
%             if(numel(app.dataLeft))
%                 tsLeft = (0:Ts:(length(app.dataLeft)-1)/Fs);
%                 
%                 plot(app.UIAxesLRA_left,tsLeft,app.dataLeft./scale)
%                 yline(app.UIAxesLRA_left,0);
%                 yline(app.UIAxesLRA_left,acc,'--r');
%                 yline(app.UIAxesLRA_left,-(acc),'--r');
%             end
            
        end
        
        function [out,stream] = sortData(app,stream, tag)
            index = strfind(stream,tag);
            if(index)
                try
                    data=NaN(length(stream),1);%max size
                    
                    ind = 1;
                    extracted = zeros(1,numel(index));
                    for i = 1 : numel(index)
                        if numel(stream) > index(i)+5 % make sure the bytes consisting the length of the frame have been received
                            len = uint32(typecast(uint8([stream(index(i)+4),stream(index(i)+5)]),'uint16'));
                            
                            ende = index(i)+5+len*2;
                            if(ende < numel(stream)) % data frame not fully read
                                
                                data(ind:ind+len-1) = typecast(uint8(stream(index(i)+6:ende)),'int16');
                                extracted(i)=ende;
                                ind = ind+len;
                            else
                                index(i) = []; % invalidate index, read in next turn
                                extracted(i) = [];
                            end
                        else
                            index(i) = []; % invalidate index, read in next turn
                            extracted(i) = [];
                        end
                        
                    end
                    for j = numel(index):-1:1
                        stream(index(j):extracted(j)) = [];
                    end
                    out = double(rmmissing(data));
                catch error
                    msgbox(error.message);
                end
            else
                out = [];
            end
        end
        
        function readStream(app)
            IndexRight_Tag = uint8('DAT0');
            IndexLeft_Tag = uint8('DAT1'); %nr COMMENT 11.21.2023
            IndexAccTimestamp_Tag = uint8('ACC_TIME');
            IndexStimTimestamp_Tag = uint8('STIMTIME');
            
            if(app.serialHandle.NumBytesAvailable>16)
                
                app.USBstream = [app.USBstream,app.serialHandle.read(app.serialHandle.NumBytesAvailable,'uint8')];
                
                stim_timestep_index = strfind(app.USBstream,IndexStimTimestamp_Tag);
                if(stim_timestep_index)
                    app.stim_timestamps = [app.stim_timestamps;double(typecast(uint8([app.USBstream(stim_timestep_index+8:stim_timestep_index+11)]),'uint32'))/1000000];
                    app.USBstream(stim_timestep_index:stim_timestep_index+11) = [];
                end
%                 
%                 [data, app.USBstream] = app.sortData(app.USBstream,IndexRight_Tag);
%                 app.dataRight = [app.dataRight;data];
%                 [data, app.USBstream] = app.sortData(app.USBstream,IndexLeft_Tag); % nR COMMENT 11.21.2023
%                 app.dataLeft = [app.dataLeft;data];
                app.USBstream = []; % delete unread stream data
            end
            
        end
        function saveData(app)
            %assignin('base', ['GG_', datestr(now,'HH_MM_SS_'),'LEFT'], double(app.dataLeft));
            %assignin('base', ['GG_', datestr(now,'HH_MM_SS_'),'RIGHT'], double(app.dataRight));
            %assignin('base', ['GG_', datestr(now,'HH_MM_SS_'),'STIMULATION'], app.stim_timestamps);
            
%             assignin('base', ['GG_s',num2str(app.subjectNumber), app.selectedLocation, '_LEFT'], double(app.dataLeft));
%             assignin('base', ['GG_s',num2str(app.subjectNumber), app.selectedLocation, '_RIGHT'], double(app.dataRight));
%             assignin('base', ['GG_s',num2str(app.subjectNumber), app.selectedLocation, '_STIM'], double(app.stim_timestamps));
             assignin('base', ['GG_s',num2str(app.subjectNumber), '_VBTESTResponses'], double(app.responses));
            
            % also:
            %writematrix(app.dataLeft,['GG_', datestr(now,'HH_MM_SS_'),'LEFT','.csv'],'Delimiter',';');
            %writematrix(app.dataRight,['GG_', datestr(now,'HH_MM_SS_'),'LEFT','.csv'],'Delimiter',';');
            
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.timerStreamHandle = timer('TimerFcn', {@app.timerStreamCallback}, 'ExecutionMode', 'FixedRate', 'Period', .5,'BusyMode','drop','StartDelay',0.5);
            app.timerPlotHandle = timer('TimerFcn', {@app.timerPlotCallback}, 'ExecutionMode', 'FixedRate', 'Period', 1,'BusyMode','queue','StartDelay',1);
            app.timerDevicesHandle = timer('TimerFcn', {@app.timerDevicesCallback}, 'ExecutionMode', 'FixedRate', 'Period', 10,'BusyMode','drop','StartDelay',10);
            start(app.timerDevicesHandle);
            
            set(app.TabGroup, 'visible', 'off');
            app.DropDown_COM.Items = serialportlist("available");
            
            app.availableDevices = blelist('name','GaitGuide');
            if(~isempty(app.availableDevices))
                app.AvailableDevicesListBox.Items = app.availableDevices.Address;
            else
                msgbox("Could not find GaitGuide-BLE device. Make sure the GaitGuide is turned on and no device is connected to it (Blue-LED is blinking slowly).")
            end
            
        end

        % Value changed function: BTN_Connect
        function BTN_ConnectPushed(app, event)
            value = app.BTN_Connect.Value;
            if(value == 1)
                SERIAL_PORT = app.DropDown_COM.Value;       % change to device port
                BAUD_RATE =  460800;
                try
                    app.serialHandle = serialport(SERIAL_PORT, BAUD_RATE);
                catch error
                    delete(app.serialHandle)
                    app.serialHandle = [];
                end
            else
                if(numel(app.serialHandle))
                    delete(app.serialHandle)
                    app.serialHandle = [];
                end
                app.DisconnectButtonPushed();
            end
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Value changed function: BTN_Record
        function BTN_RecordValueChanged(app, event)
            value = app.BTN_Record.Value;
            stop(app.timerStreamHandle);
            stop(app.timerPlotHandle);
            if(numel(app.serialHandle))
                if(value == 1)
                    app.dataLeft = [];
                    app.dataRight = [];
                    app.USBstream =  [];
                    app.acc_timestamp = [];
                    app.stim_timestamps = [];
                    write(app.serialHandle,1,"uint8");
                    start(app.timerStreamHandle);
                    app.isStreaming = true;
                    start(app.timerPlotHandle);
                else
                    write(app.serialHandle,1,"uint8");
                    
                    app.isStreaming = false;
                    stop(app.timerStreamHandle);
                    stop(app.timerPlotHandle);
                    app.serialHandle.flush
                    app.saveData();
                end
            else
                app.BTN_Record.ValueChangedFcn(0);
            end
            
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            app.GaitGuide = [];
            app.Lamp.Color = "#a6a6a6";
            set(app.TabGroup, 'visible', 'off');
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            try
                app.GaitGuide = ble(app.AvailableDevicesListBox.Value);
                if(app.GaitGuide.Connected)
                    app.Lamp.Color = "blue";
                    set(app.TabGroup, 'visible', 'on');
                end
            catch error
                msgbox(error.message);
                
                app.Lamp.Color = "#a6a6a6";
                set(app.TabGroup, 'visible', 'off');
            end
        end

        % Button pushed function: LRA_leftButton
        function LRA_leftButtonPushed(app, event)
            if(app.GaitGuide.Connected)
                try
                    LRA_left = characteristic(app.GaitGuide, "1111","1113");
                    LRA_left.write(app.DurationmsEditField.Value,'uint16');
                catch error
                    msgbox(error.message);
                end
            end
        end

        % Button pushed function: LRA_rightButton
        function LRA_rightButtonPushed(app, event)
            if(app.GaitGuide.Connected)
                try
                    LRA_right = characteristic(app.GaitGuide, "1111","1114");
                    LRA_right.write(app.DurationmsEditField.Value,'uint16');
                catch error
                    msgbox(error.message);
                end
            end
        end

        % Value changed function: AmplitudeEditField
        function AmplitudeEditFieldValueChanged(app, event)
            if(app.GaitGuide.Connected)
                try
                    Amplitude = characteristic(app.GaitGuide, "1111","1112");
                    Amplitude.write(app.AmplitudeEditField.Value,'uint8');
                catch error
                    msgbox(error.message);
                end
            end
        end

        % Value changed function: AvailableDevicesListBox
        function AvailableDevicesListBoxValueChanged(app, event)
            
            app.GaitGuide = [];
            app.Lamp.Color = "#a6a6a6";
            set(app.TabGroup, 'visible', 'off');
            
        end

        % Value changed function: subjectNumberEditField
        function subjectNumberEditFieldValueChanged(app, event)
            app.subjectNumber = app.subjectNumberEditField.Value;
            
        end

        % Selection changed function: LocationButtonGroup
        function LocationButtonGroupSelectionChanged(app, event)
            selectedButton = app.LocationButtonGroup.SelectedObject;
            
            if selectedButton == app.LateralButton
                app.selectedLocation = '_lat';
            elseif selectedButton == app.PosteriorCalibrationButton
                app.selectedLocation = '_post';
            elseif selectedButton == app.MedialButton
                app.selectedLocation = '_med';
            elseif selectedButton == app.AnteriorButton
                app.selectedLocation = '_ant';
            end
            
        end

        % Selection changed function: AmplitudeButtonGroup
        function AmplitudeButtonGroupSelectionChanged(app, event)
            
            if(app.GaitGuide.Connected)
                selectedButtonAmp = app.AmplitudeButtonGroup.SelectedObject;
                
                try
                    Amplitude = characteristic(app.GaitGuide, "1111", "1112");
                    
                    if selectedButtonAmp == app.Button20
                        Amplitude.write(223, 'uint8')%Amplitude.write(159, 'uint8');
                        
                    elseif selectedButtonAmp == app.Button40
                        Amplitude.write(199, 'uint8')%Amplitude.write(183, 'uint8');
                        
                    elseif selectedButtonAmp == app.Button60
                        Amplitude.write(175, 'uint8')%Amplitude.write(207, 'uint8');
                        
                    elseif selectedButtonAmp == app.Button80
                        Amplitude.write(151, 'uint8')%Amplitude.write(231, 'uint8');
                        
                    elseif selectedButtonAmp == app.Button100
                        Amplitude.write(127, 'uint8')%Amplitude.write(255, 'uint8');
                        
                    end
                    
                catch error
                    msgbox(error.message);
                end
            end


        end

        % Button pushed function: GoLRARightButton
        function GoLRARightButtonPushed(app, event)
            if app.GaitGuide.Connected
                try
                    LRA_right = characteristic(app.GaitGuide, "1111", "1114");
                    
                    % loop to cue 3x with a 5s-delay
                    for i = 1:3
                        LRA_right.write(100, 'uint16');
                        pause(5);
                    end
                catch error
                    msgbox(error.message);
                end
            end
        end

        % Callback function
        function RespondButtonPushed(app, event)
            choice = uiconfirm(app.UIFigure, 'Which duration was LONGER', 'Pick one:', ...
                      'Options', {'A', 'B'}, 'DefaultOption', 'B');

            % Check the user's choice
            if strcmp(choice, 'A')
                disp('User selected A to be longer');
                % Add your code for the "Yes" case here
            else
                disp('User selected B to be longer');
                % Add your code for the "No" case here
            end
        end

        % Button pushed function: LoadPairingsButton
        function LoadPairingsButtonPushed(app, event)
            app.pairings = load("setPairings2.mat"); %setpairings.mat
        end

        % Button pushed function: CuePairedButton
        function CuePairedButtonPushed(app, event)
            
            Amplitude = characteristic(app.GaitGuide, "1111", "1112");
            Amplitude.write(127, 'uint8')

            LRA_right = characteristic(app.GaitGuide, "1111", "1114");
            LRA_left = characteristic(app.GaitGuide, "1111","1113");

            if app.pairings.setPairings2(app.pairings_iterations,3) == 0
                LRA_right.write(app.pairings.setPairings2(app.pairings_iterations,1), 'uint16');
                pause(2);
                LRA_right.write(app.pairings.setPairings2(app.pairings_iterations,2), 'uint16');
                pause(2);
            else
                LRA_left.write(app.pairings.setPairings2(app.pairings_iterations,1), 'uint16');
                pause(2);
                LRA_left.write(app.pairings.setPairings2(app.pairings_iterations,2), 'uint16');
                pause(2);
            end

            choice = uiconfirm(app.UIFigure, 'Which duration was LONGER', 'Pick one:', ...
                'Options', {'A', 'B'}, 'DefaultOption', 'B');

            % Check the user's choice
            if strcmp(choice, 'A')
                disp('User selected A to be longer');
                if app.pairings.setPairings2(app.pairings_iterations,1) > app.pairings.setPairings2(app.pairings_iterations,2)
                    app.responses(app.pairings_iterations) = 1;
                else
                    app.responses(app.pairings_iterations) = 0;
                end
            else
                disp('User selected B to be longer');
                if app.pairings.setPairings2(app.pairings_iterations,1) < app.pairings.setPairings2(app.pairings_iterations,2)
                    app.responses(app.pairings_iterations) = 1;
                else
                    app.responses(app.pairings_iterations) = 0;
                end
            end
            
            if app.pairings_iterations == 3%60 %30
                YN = uiconfirm(app.UIFigure, ["Final count correct: " + num2str(sum(app.responses))], 'Closing');
                app.saveData();
            end
            app.pairings_iterations = app.pairings_iterations+1;
            app.Label.Text = num2str(app.pairings_iterations);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {782, 782};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {243, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.Title = 'Manual';
            app.DurationmsEditFieldLabel = uilabel(app.ManualTab);
            app.DurationmsEditFieldLabel.Text = 'Duration [ms]';
            app.DurationmsEditField = uieditfield(app.ManualTab, 'numeric');
            app.DurationmsEditField.Limits = [0 5000];
            app.DurationmsEditField.Value = 100;
            app.AmplitudeEditFieldLabel = uilabel(app.ManualTab);
            app.AmplitudeEditFieldLabel.Text = 'Amplitude';
            app.AmplitudeEditField = uieditfield(app.ManualTab, 'numeric');
            app.AmplitudeEditField.Limits = [0 255];
            app.AmplitudeEditField.ValueChangedFcn = createCallbackFcn(app, @AmplitudeEditFieldValueChanged, true);
            app.AmplitudeEditField.Value = 255;
            app.LRA_leftButton = uibutton(app.ManualTab, 'push');
            app.LRA_leftButton.ButtonPushedFcn = createCallbackFcn(app, @LRA_leftButtonPushed, true);
            app.LRA_leftButton.Text = 'Left';
            app.LRA_rightButton = uibutton(app.ManualTab, 'push');
            app.LRA_rightButton.ButtonPushedFcn = createCallbackFcn(app, @LRA_rightButtonPushed, true);
            app.LRA_rightButton.Text = 'Right';
            app.AutoTab = uitab(app.TabGroup);
            app.AutoTab.Title = 'Auto';
            app.LocationButtonGroup = uibuttongroup(app.AutoTab);
            app.LocationButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LocationButtonGroupSelectionChanged, true);
            app.LocationButtonGroup.Title = 'Location';
            app.AnteriorButton = uiradiobutton(app.LocationButtonGroup);
            app.AnteriorButton.Text = '(1) Anterior';
            app.AnteriorButton.Value = true;
            app.MedialButton = uiradiobutton(app.LocationButtonGroup);
            app.MedialButton.Text = '(2) Medial';
            app.PosteriorCalibrationButton = uiradiobutton(app.LocationButtonGroup);
            app.PosteriorCalibrationButton.Text = '(3) Posterior/Calibration';
            app.LateralButton = uiradiobutton(app.LocationButtonGroup);
            app.LateralButton.Text = '(4) Lateral';
            app.AmplitudeButtonGroup = uibuttongroup(app.AutoTab);
            app.AmplitudeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @AmplitudeButtonGroupSelectionChanged, true);
            app.AmplitudeButtonGroup.Title = 'Amplitude';
            app.Button100 = uiradiobutton(app.AmplitudeButtonGroup);
            app.Button100.Text = '127 (100%)';
            app.Button100.Value = true;
            app.Button80 = uiradiobutton(app.AmplitudeButtonGroup);
            app.Button80.Text = '151 (80%)';
            app.Button60 = uiradiobutton(app.AmplitudeButtonGroup);
            app.Button60.Text = '175 (60%)';
            app.Button40 = uiradiobutton(app.AmplitudeButtonGroup);
            app.Button40.Text = '199 (40%)';
            app.Button20 = uiradiobutton(app.AmplitudeButtonGroup);
            app.Button20.Text = '223 (20%)';
            app.GoLRARightButton = uibutton(app.AutoTab, 'push');
            app.GoLRARightButton.ButtonPushedFcn = createCallbackFcn(app, @GoLRARightButtonPushed, true);
            app.GoLRARightButton.Text = 'Go LRA Right';
            app.TestingTab = uitab(app.TabGroup);
            app.TestingTab.Title = 'Testing';
            app.LoadPairingsButton = uibutton(app.TestingTab, 'push');
            app.LoadPairingsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPairingsButtonPushed, true);
            app.LoadPairingsButton.Text = 'Load Pairings';
            app.CuePairedButton = uibutton(app.TestingTab, 'push');
            app.CuePairedButton.ButtonPushedFcn = createCallbackFcn(app, @CuePairedButtonPushed, true);
            app.CuePairedButton.Text = 'Cue Paired';
            app.IterationnumberLabel = uilabel(app.TestingTab);
            app.IterationnumberLabel.Text = 'Iteration number: ';
            app.Label = uilabel(app.TestingTab);
            app.Label.Text = '1';
            app.Acceleration = uipanel(app.GridLayout2);
            app.Acceleration.Title = 'Acceleration';
            app.DropDown_COM = uidropdown(app.Acceleration);
            app.DropDown_COM.Items = {'COM11'};
            app.DropDown_COM.Value = 'COM11';
            app.BTN_Record = uibutton(app.Acceleration, 'state');
            app.BTN_Record.ValueChangedFcn = createCallbackFcn(app, @BTN_RecordValueChanged, true);
            app.BTN_Record.Text = 'Record';
            app.BTN_Connect = uibutton(app.Acceleration, 'state');
            app.BTN_Connect.ValueChangedFcn = createCallbackFcn(app, @BTN_ConnectPushed, true);
            app.BTN_Connect.Text = 'Connect';
            app.subjectNumberEditFieldLabel = uilabel(app.Acceleration);
            app.subjectNumberEditFieldLabel.Text = 'subjectNumber';
            app.subjectNumberEditField = uieditfield(app.Acceleration, 'text');
            app.subjectNumberEditField.ValueChangedFcn = createCallbackFcn(app, @subjectNumberEditFieldValueChanged, true);
            app.Bluetooth = uipanel(app.GridLayout2);
            app.Bluetooth.Title = 'Bluetooth';
            app.Lamp = uilamp(app.Bluetooth);
            app.DisconnectButton = uibutton(app.Bluetooth, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.ConnectButton = uibutton(app.Bluetooth, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.AvailableDevicesListBox = uilistbox(app.Bluetooth);
            app.AvailableDevicesListBox.ValueChangedFcn = createCallbackFcn(app, @AvailableDevicesListBoxValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxesLRA_left = uiaxes(app.GridLayout3);
            title(app.UIAxesLRA_left, 'Left')
            xlabel(app.UIAxesLRA_left, 'X')
            ylabel(app.UIAxesLRA_left, 'Y')
            zlabel(app.UIAxesLRA_left, 'Z')
            app.UIAxesLRA_left.YLim = [-16 16];
            app.UIAxesLRA_right = uiaxes(app.GridLayout3);
            title(app.UIAxesLRA_right, 'Right')
            xlabel(app.UIAxesLRA_right, 'X')
            ylabel(app.UIAxesLRA_right, 'Y')
            zlabel(app.UIAxesLRA_right, 'Z')
            app.UIAxesLRA_right.YLim = [-16 16];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = exp_02_vibroSensitivityTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end